classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartSetupPanel              matlab.ui.container.Panel
        RotateDropDown               matlab.ui.control.DropDown
        RotateDropDownLabel          matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        VanishPointNumDropDown       matlab.ui.control.DropDown
        VanishPointNumDropDownLabel  matlab.ui.control.Label
        ForegroundNumEditField       matlab.ui.control.NumericEditField
        ForegroundNumEditFieldLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        GUIModeTab                   matlab.ui.container.Tab
        StepSizeSlider               matlab.ui.control.Slider
        ControlrangeLabel            matlab.ui.control.Label
        ZoomoutButton                matlab.ui.control.Button
        ZoominButton                 matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        Button_up                    matlab.ui.control.Button
        Button_down                  matlab.ui.control.Button
        Button_right                 matlab.ui.control.Button
        Button_left                  matlab.ui.control.Button
        CameraPositionTab            matlab.ui.container.Tab
        ApplyButton                  matlab.ui.control.Button
        ZDirectionEditField          matlab.ui.control.NumericEditField
        ZDirectionEditFieldLabel     matlab.ui.control.Label
        YDirectionEditField          matlab.ui.control.NumericEditField
        YDirectionEditFieldLabel     matlab.ui.control.Label
        XDirectionEditField          matlab.ui.control.NumericEditField
        XDirectionEditFieldLabel     matlab.ui.control.Label
        CameraPositionDirectionVectorLabel  matlab.ui.control.Label
        ZCoordinateEditField         matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel    matlab.ui.control.Label
        YCoordinateEditField         matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel    matlab.ui.control.Label
        XCoordinateEditField         matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel    matlab.ui.control.Label
        TechnischeUniversittMnchenComputerVisionChallengeGruppe25Label  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ImagesDatensatzLabel         matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        LoadButton                   matlab.ui.control.Button
        ImageAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        current_imagefile % Description
    end
    
    properties (Access = private)
        Vanish_Point_Number = 1; %how many VP in total, 1 or 2;
        Foreground_Number = 0; % how many Foreground in total, 0,1,2,3.... blablabla
        Rotate_Flag ="No";
        Rotate %gui Rotate or shift.
        step_size = 1;  % step_size for shift, rotate and zoom in/out;
        camposition = [0,0,0]; % Description
        cam_direction_vector = [0,0,0]; % Description
    end
    
    methods (Access = private)
        
        %update image on the top.
        function updateimage(app,imagefile)
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                    app.current_imagefile = imagefile; % 比如'xxx.png'
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
           
            
            % Image must be grayscale or truecolor.
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
 
                case 3
                    % Display the truecolor image 
                    imagesc(app.ImageAxes,im);

                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end         
        end


        function [vx, vy, irx, iry, orx, ory] = TIP_GUI(app,im)
            % Given an image, the user pick the "inner rectangle" 
            % and the vanishing point. The user must first click on the upper
            % left corner of the rectangle, then the lower right corner.  Then,
            % click on the vanishing point until the user is satisfied.  When done, hit
            % "ENTER".  
            % The function returns the vanishing point (vx,vy) and the coordinates of
            % the inner rectangle and the outer polygon.
        
        [ymax,xmax,cdepth] = size(im);
        
        imshow(im);
        
        % get the the inner rectangle
        [rx,ry] = ginput(2);
        
        
        hold on;
        irx = round([rx(1) rx(2) rx(2) rx(1) rx(1)]);
        iry =  round([ry(1) ry(1) ry(2) ry(2) ry(1)]);
        plot(irx,iry,'b'); 
        hold off;
        
        %  user  specify the vanishing point
        while 1
          
          % get the vanishing point
          [vxnew,vynew,button] = ginput(1);
        
          % if pressed ENTER, quit the loop
          if (isempty(button))
            break;
          end
          vx = vxnew; vy=vynew;
          
          % find where the line from VP through inner rectangle hits the edge of image
          [ox,oy] = find_corner(vx,vy,irx(1),iry(1),0,0);
          orx(1) = ox;  ory(1) = oy;
          [ox,oy] = find_corner(vx,vy,irx(2),iry(2),xmax,0);
          orx(2) = ox;  ory(2) = oy;
          [ox,oy] = find_corner(vx,vy,irx(3),iry(3),xmax,ymax);
          orx(3) = ox;  ory(3) = oy;
          [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,ymax);
          orx(4) = ox;  ory(4) = oy;
          orx = round(orx);
          ory = round(ory);
         
          % draw
          imshow(im);
          hold on;
          irx = round([rx(1) rx(2) rx(2) rx(1) rx(1)]);
          iry =  round([ry(1) ry(1) ry(2) ry(2) ry(1)]);
          plot(irx,iry,'b'); 
          plot([vx irx(1)], [vy iry(1)], 'r-.');
          plot([orx(1) irx(1)], [ory(1) iry(1)], 'r');
          plot([vx irx(2)], [vy iry(2)], 'r-.');
          plot([orx(2) irx(2)], [ory(2) iry(2)], 'r');
          plot([vx irx(3)], [vy iry(3)], 'r-.');
          plot([orx(3) irx(3)], [ory(3) iry(3)], 'r');
          plot([vx irx(4)], [vy iry(4)], 'r-.');
          plot([orx(4) irx(4)], [ory(4) iry(4)], 'r');
          hold off;
          spidery_mesh(vx,vy,size(im))
          drawnow;
        end
                    
        end
        
        function [firx, firy,gbim, g_sele,alpha_sele,gf] = TIP_GUI_f(app,gbim,gvx, gvy, irx, iry, orx,ory)
            
        [ymax,xmax,cdepth] = size(im);
        
        % select picture
        imshow(im);
        
        % select the foreground(s)
        [xmins,xmaxs,ymins,ymaxs,gim,g_sele,alpha_sele,gf] = buildselection(im); %user defined selected picture
        %g: selected foreground
        %gim: origin picture with blurred picture 
        %xmin,xmax,ymin,ymax: outer frame of the foregrounds
        %g_sele: foreground rectangles
       
        
        figure;
        imshow(im);
        
        % draw the rectangle which surrounds the foreground
        frx = [xmins;xmaxs];
        fry = [ymins;ymaxs];
        imshow(im);
        hold on;
        
        firx = round([fry(1) fry(2) fry(2) fry(1) fry(1)]);
        firy =  round([frx(1) frx(1) frx(2) frx(2) frx(1)]);
        plot(firx,firy,'black'); 
        hold off;
        
         % draw
        imshow(im);
        hold on;
        plot(irx,iry,'b'); 
        plot(firx,firy,'black'); 
        plot([vx irx(1)], [vy iry(1)], 'r-.');
        plot([orx(1) irx(1)], [ory(1) iry(1)], 'r');
        plot([vx irx(2)], [vy iry(2)], 'r-.');
        plot([orx(2) irx(2)], [ory(2) iry(2)], 'r');
        plot([vx irx(3)], [vy iry(3)], 'r-.');
        plot([orx(3) irx(3)], [ory(3) iry(3)], 'r');
        plot([vx irx(4)], [vy iry(4)], 'r-.');
        plot([orx(4) irx(4)], [ory(4) iry(4)], 'r');
        spidery_mesh(vx,vy,size(im))
        hold off;
    
        drawnow;
        end
        
        %%
        function [big_im,big_im_alpha,vx,vy,ceilrx,ceilry,floorrx,floorry,...
            leftrx,leftry,rightrx,rightry,backrx,backry,backfirx,backfiry] = ...
            TIP_get5rects_f(app,im,vx,vy,irx,iry,orx,ory, firx, firy)

        %expand the image so that each "face" of the box is a proper rectangle
        [ymax,xmax,cdepth] = size(im);
        lmargin = -min(orx);
        rmargin = max(orx) - xmax;
        tmargin = -min(ory);
        bmargin = max(ory) - ymax;
        big_im = zeros([ymax+tmargin+bmargin xmax+lmargin+rmargin cdepth]);
        big_im_alpha = zeros([size(big_im,1) size(big_im,2)]);
        big_im(tmargin+1:end-bmargin,lmargin+1:end-rmargin,:) = im2double(im);
        big_im_alpha(tmargin+1:end-bmargin,lmargin+1:end-rmargin) = 1;
        
        
        % update all variables for the new image
        vx = vx + lmargin;
        vy = vy + tmargin;
        irx = irx + lmargin;
        iry = iry + tmargin;
        orx = orx + lmargin;
        ory = ory + tmargin;
        backfirx = firx + lmargin;
        backfiry = firy + tmargin;
   
        % define the 5 rectangles
        
        % ceiling 
        ceilrx = [orx(1) orx(2) irx(2) irx(1)];
        ceilry = [ory(1) ory(2) iry(2) iry(1)];
        if (ceilry(1)<tmargin && ceilry(2)<tmargin)
            ceilry(1) =tmargin;
            ceilrx(1) = round(find_line_x(vx,vy,irx(1),iry(1),ceilry(1)));
            ceilry(2)=tmargin;
            ceilrx(2) = round(find_line_x(vx,vy,irx(2),iry(2),ceilry(2)));
        else
            if (ceilry(1) < ceilry(2))
                 ceilrx(1) = round(find_line_x(vx,vy,ceilrx(1),ceilry(1),ceilry(2)));
                 ceilry(1) = ceilry(2);
            else
                 ceilrx(2) = round(find_line_x(vx,vy,ceilrx(2),ceilry(2),ceilry(1)));
                 ceilry(2) = ceilry(1);
            end
        end
        % floor
        floorrx = [irx(4) irx(3) orx(3) orx(4)];
        floorry = [iry(4) iry(3) ory(3) ory(4)];
        if (floorry(3)>ymax+tmargin && floorry(4)>ymax+tmargin)
            floorry(3) =ymax+tmargin;
            floorrx(3) = round(find_line_x(vx,vy,irx(3),iry(3),floorry(3)));
            floorry(4)=ymax+tmargin;
            floorrx(4) = round(find_line_x(vx,vy,irx(4),iry(4),floorry(4)));
        else
            if (floorry(3) > floorry(4))
                 floorrx(3) = round(find_line_x(vx,vy,floorrx(3),floorry(3),floorry(4)));
                 floorry(3) = floorry(4);
            else
                 floorrx(4) = round(find_line_x(vx,vy,floorrx(4),floorry(4),floorry(3)));
                 floorry(4) = floorry(3);
            end
        end
        
        % left
        leftrx = [orx(1) irx(1) irx(4) orx(4)];
        leftry = [ory(1) iry(1) iry(4) ory(4)];
        if (leftrx(1)<lmargin && leftrx(4)<lmargin)
            leftrx(1) = lmargin;
            leftry(1)= round(find_line_y(vx,vy,irx(1),iry(1),leftrx(1)));
            leftrx(4) = lmargin;
            leftry(4) = round(find_line_y(vx,vy,irx(4),iry(4),leftrx(4)));
        else
            if (leftrx(1) < leftrx(4))
                 leftry(1) = round(find_line_y(vx,vy,leftrx(1),leftry(1),leftrx(4)));
                 leftrx(1) = leftrx(4);
            else
                 leftry(4) = round(find_line_y(vx,vy,leftrx(4),leftry(4),leftrx(1)));
                 leftrx(4) = leftrx(1);
            end
        end
        
        % right
        rightrx = [irx(2) orx(2) orx(3) irx(3)];
        rightry = [iry(2) ory(2) ory(3) iry(3)];
        if (rightrx(2)>xmax+lmargin && rightrx(3)>xmax+lmargin)
            rightrx(2) = xmax+lmargin;
            rightry(2)= round(find_line_y(vx,vy,irx(2),iry(2),rightrx(2)));
            rightrx(3) = xmax+lmargin;
            rightry(3) = round(find_line_y(vx,vy,irx(3),iry(3),rightrx(3)));
        else
            if (rightrx(2) > rightrx(3))
                 rightry(2) = round(find_line_y(vx,vy,rightrx(2),rightry(2),rightrx(3)));
                 rightrx(2) = rightrx(3);
            else
                rightry(3) = round(find_line_y(vx,vy,rightrx(3),rightry(3),rightrx(2)));
                rightrx(3) = rightrx(2);
            end
        end

        backrx = irx;
        backry = iry;

        end
        
        function [big_im,big_im_alpha,vx,vy,ceilrx,ceilry,floorrx,floorry,...
            leftrx,leftry,rightrx,rightry,backrx,backry] = ...
            TIP_get5rects(app,im,vx,vy,irx,iry,orx,ory)
        % expand the image so that each "face" of the box is a proper rectangle
        [ymax,xmax,cdepth] = size(im);
        lmargin = -min(orx);
        rmargin = max(orx) - xmax;
        tmargin = -min(ory);
        bmargin = max(ory) - ymax;
        big_im = zeros([ymax+tmargin+bmargin xmax+lmargin+rmargin cdepth]);
        big_im_alpha = zeros([size(big_im,1) size(big_im,2)]);
        big_im(tmargin+1:end-bmargin,lmargin+1:end-rmargin,:) = im2double(im);
        big_im_alpha(tmargin+1:end-bmargin,lmargin+1:end-rmargin) = 1;
        
        
        % update all variables for the new image
        vx = vx + lmargin;
        vy = vy + tmargin;
        irx = irx + lmargin;
        iry = iry + tmargin;
        orx = orx + lmargin;
        ory = ory + tmargin;
        
        
        % define the 5 rectangles
        
        % ceiling 
        ceilrx = [orx(1) orx(2) irx(2) irx(1)];
        ceilry = [ory(1) ory(2) iry(2) iry(1)];
        if (ceilry(1)<tmargin && ceilry(2)<tmargin)
            ceilry(1) =tmargin;
            ceilrx(1) = round(find_line_x(vx,vy,irx(1),iry(1),ceilry(1)));
            ceilry(2)=tmargin;
            ceilrx(2) = round(find_line_x(vx,vy,irx(2),iry(2),ceilry(2)));
        else
            if (ceilry(1) < ceilry(2))
                 ceilrx(1) = round(find_line_x(vx,vy,ceilrx(1),ceilry(1),ceilry(2)));
                 ceilry(1) = ceilry(2);
            else
                 ceilrx(2) = round(find_line_x(vx,vy,ceilrx(2),ceilry(2),ceilry(1)));
                 ceilry(2) = ceilry(1);
            end
        end
        % floor
        floorrx = [irx(4) irx(3) orx(3) orx(4)];
        floorry = [iry(4) iry(3) ory(3) ory(4)];
        if (floorry(3)>ymax+tmargin && floorry(4)>ymax+tmargin)
            floorry(3) =ymax+tmargin;
            floorrx(3) = round(find_line_x(vx,vy,irx(3),iry(3),floorry(3)));
            floorry(4)=ymax+tmargin;
            floorrx(4) = round(find_line_x(vx,vy,irx(4),iry(4),floorry(4)));
        else
            if (floorry(3) > floorry(4))
                 floorrx(3) = round(find_line_x(vx,vy,floorrx(3),floorry(3),floorry(4)));
                 floorry(3) = floorry(4);
            else
                 floorrx(4) = round(find_line_x(vx,vy,floorrx(4),floorry(4),floorry(3)));
                 floorry(4) = floorry(3);
            end
        end
        
        % left
        leftrx = [orx(1) irx(1) irx(4) orx(4)];
        leftry = [ory(1) iry(1) iry(4) ory(4)];
        if (leftrx(1)<lmargin && leftrx(4)<lmargin)
            leftrx(1) = lmargin;
            leftry(1)= round(find_line_y(vx,vy,irx(1),iry(1),leftrx(1)));
            leftrx(4) = lmargin;
            leftry(4) = round(find_line_y(vx,vy,irx(4),iry(4),leftrx(4)));
        else
            if (leftrx(1) < leftrx(4))
                 leftry(1) = round(find_line_y(vx,vy,leftrx(1),leftry(1),leftrx(4)));
                 leftrx(1) = leftrx(4);
            else
                 leftry(4) = round(find_line_y(vx,vy,leftrx(4),leftry(4),leftrx(1)));
                 leftrx(4) = leftrx(1);
            end
        end
        
        % right
        rightrx = [irx(2) orx(2) orx(3) irx(3)];
        rightry = [iry(2) ory(2) ory(3) iry(3)];
        if (rightrx(2)>xmax+lmargin && rightrx(3)>xmax+lmargin)
            rightrx(2) = xmax+lmargin;
            rightry(2)= round(find_line_y(vx,vy,irx(2),iry(2),rightrx(2)));
            rightrx(3) = xmax+lmargin;
            rightry(3) = round(find_line_y(vx,vy,irx(3),iry(3),rightrx(3)));
        else
            if (rightrx(2) > rightrx(3))
                 rightry(2) = round(find_line_y(vx,vy,rightrx(2),rightry(2),rightrx(3)));
                 rightrx(2) = rightrx(3);
            else
                 rightry(3) = round(find_line_y(vx,vy,rightrx(3),rightry(3),rightrx(2)));
                 rightrx(3) = rightrx(2);
            end
        end
        %
        backrx = irx;
        backry = iry;
            
        end
   
        %%
        function adjIma = proj_adjust(app,orgIma,pos,focLen)
        szOrgIma = size(orgIma);
        orgPos = pos(:,1:4);
        flag = pos(:,5);
        mid = [szOrgIma(2)/2,szOrgIma(1)/2]';
        
        % calculate the original proportion of the rectangle based on the top line
        % with VP in y direction and left line with VP in x direction
        if flag(1)>flag(2)
            wid = norm([orgPos(1,1)-orgPos(1,2),orgPos(2,1)-orgPos(2,2)]);  % width of the selected quadrangle
            dep = focLen * (norm([orgPos(1,1)-orgPos(1,2),orgPos(2,1)-orgPos(2,2)])/norm([orgPos(1,3)-orgPos(1,4),orgPos(2,3)-orgPos(2,4)])-1);
            c2 = norm(cross([mid-orgPos(:,1);0],[orgPos(:,1)-orgPos(:,2);0]))/wid;
            x1 = find_line_x(orgPos(1,1),orgPos(2,1),orgPos(1,4),orgPos(2,4),mid(2));
            x2 = find_line_x(orgPos(1,2),orgPos(2,2),orgPos(1,3),orgPos(2,3),mid(2));
            b1 = abs(x1 - x2);
            d1 = focLen * (norm([orgPos(1,1)-orgPos(1,2),orgPos(2,1)-orgPos(2,2)])/b1 - 1);
            hei = sqrt((c2*dep/d1)^2+dep^2);
        else
            hei = norm([orgPos(1,1)-orgPos(1,4),orgPos(2,1)-orgPos(2,4)]);  % height of the selected quadrangle
            dep = focLen * (norm([orgPos(1,1)-orgPos(1,4),orgPos(2,1)-orgPos(2,4)])/norm([orgPos(1,3)-orgPos(1,2),orgPos(2,3)-orgPos(2,2)])-1);
            c2 = norm(cross([mid-orgPos(:,1);0],[orgPos(:,1)-orgPos(:,4);0]))/hei;
            y1 = find_line_y(orgPos(1,1),orgPos(2,1),orgPos(1,2),orgPos(2,2),mid(1));
            y2 = find_line_y(orgPos(1,3),orgPos(2,3),orgPos(1,4),orgPos(2,4),mid(1));
            b1 = abs(y1-y2);
            d1 = focLen * (norm([orgPos(1,1)-orgPos(1,4),orgPos(2,1)-orgPos(2,4)])/b1 - 1);
            wid = sqrt((c2*dep/d1)^2+dep^2);
        end


        % the new rectangle has the same width and height as the orginal one
        adjPos = [orgPos(1,1), orgPos(1,1)+wid, orgPos(1,1)+wid, orgPos(1,1);
                orgPos(2,1), orgPos(2,1), orgPos(2,1)+hei, orgPos(2,1)+hei];
        
        % compute homogramphy matrix adjPos = h * orgPos
        [h, ~] = computeH(orgPos, adjPos);
        
        
        % compute the adjusted image
        t = projective2d(h);
        adjIma = imwarp(orgIma, t);
        end
        
        function spidery_mesh(app,vx,vy,sizeIma,varargin)
            
        hold on
        % draw the vanishing point
        vp = scatter(vx,vy);
        vp.SizeData = 100;
        vp.LineWidth = 0.6;
        vp.MarkerEdgeColor = 'b';
        vp.MarkerFaceColor = [0 0.5 0.5];
        n = 20;
        % draw the rays from the vanishing point
        if (nargin ==3)
        theta = linspace(-pi/2,pi/2,n);
        theta = theta(2:end-1);
        
        xline(vx,'Color','b')
        ySta = vy - tan(theta) * vx;
        yEnd = vy + tan(theta) * (sizeIma(2) - vx);
        line([zeros(1,n-2);ones(1,n-2)*sizeIma(2)],[ySta;yEnd],'Color','b')
        
        end
        % draw lines
        if (nargin > 3)
            ver5Rec = varargin{1};
        
            yPoints = linspace(ver5Rec(7,2),ver5Rec(1,2),n);
            xPoints = linspace(ver5Rec(1,1),ver5Rec(2,1),n);
            line([ones(1,n)*ver5Rec(1,1);ones(1,n)*ver5Rec(2,1)],[yPoints;yPoints],'Color','k')
            line([xPoints;xPoints],[ones(1,n)*ver5Rec(7,2);ones(1,n)*ver5Rec(1,2)],'Color','k')
        
            xCell = find_line_x(vx,vy,xPoints,ver5Rec(7,2),ver5Rec(9,2));
            line([xPoints;xCell],[ones(1,n)*ver5Rec(7,2);ones(1,n)*ver5Rec(9,2)],'Color','k')
        
            xFloor = find_line_x(vx,vy,xPoints,ver5Rec(1,2),ver5Rec(3,2));
            line([xPoints;xFloor],[ones(1,n)*ver5Rec(1,2);ones(1,n)*ver5Rec(3,2)],'Color','k')
        
            yLeft = find_line_y(vx,vy,ver5Rec(1,1),yPoints,ver5Rec(5,1));
            line([ones(1,n)*ver5Rec(1,1);ones(1,n)*ver5Rec(5,1)],[yPoints;yLeft],'Color','k')
        
            yRight = find_line_y(vx,vy,ver5Rec(2,1),yPoints,ver5Rec(6,1));
            line([ones(1,n)*ver5Rec(2,1);ones(1,n)*ver5Rec(6,1)],[yPoints;yRight],'Color','k')
        
            % line in the plane, which parallel to the photo plane
            ax = min(ver5Rec(9,1),ver5Rec(11,1));
            ay = min(ver5Rec(9,2),ver5Rec(11,2));
            d = floor((ver5Rec(7,2)-ay)/5);
            bx = find_line_x(ax,ay,vx,vy,ay+d);
            by = ay + d;
            i = 0; % counter
            while(norm([bx-ver5Rec(7,1),by-ver5Rec(7,2)])> norm([ax-bx,ay-by]))
                i = i+1;
                fun = @(x)cross_ratio(x,[vx,vy,ax,ay,bx,by]);
                x = fsolve(fun,[(3*bx-ax)/2,(3*by-ay)/2]);
                cx = x(1);
                cy = x(2);
                tempx = find_line_x(vx,vy,ver5Rec(10,1),ver5Rec(10,2),by);
                tempy = find_line_y(vx,vy,ver5Rec(6,1),ver5Rec(6,2),tempx);
                if (by > ver5Rec(9,2))
                    line([bx,tempx],[by,by],'Color','k');
                end
                if (tempx < ver5Rec(12,1))
                    line([tempx,tempx],[by,tempy],'Color','k');
                end
                if (bx > ver5Rec(11,1))
                    line([bx,bx],[by,tempy],'Color','k');
                end
                if (tempy < ver5Rec(3,2))
                    line([bx,tempx],[tempy,tempy],'Color','k');
                end
                if cx > ver5Rec(7,1)
                    break
                end
                ax = bx;
                ay = by;
                bx = cx;
                by = cy;
            end
        end
        
        % quit
        hold off
        end
        
        %%
        function [X,Y] = node(app,X1,Y1,X2,Y2)
        if X1(1)==Y1(1)
           X=X1(1);
           k2=(Y2(2)-X2(2))/(Y2(1)-X2(1));
           b2=X2(2)-k2*X2(1); 
           Y=k2*X+b2;
        end
        if X2(1)==Y2(1)
           X=X2(1);
           k1=(Y1(2)-X1(2))/(Y1(1)-X1(1));
           b1=X1(2)-k1*X1(1);
           Y=k1*X+b1;
        end
        if X1(1)~=Y1(1)&&X2(1)~=Y2(1)
           k1=(Y1(2)-X1(2))/(Y1(1)-X1(1));
           k2=(Y2(2)-X2(2))/(Y2(1)-X2(1));
           b1=X1(2)-k1*X1(1);
           b2=X2(2)-k2*X2(1);
            if k1==k2
              X=[];
              Y=[];
           else
           X=(b2-b1)/(k1-k2);
           Y=k1*X+b1;
           end
        end
        end

        function gbim = filtered(im, x, y)
        % make the selected area of foreground blurred
         A(:,:,1) = im(x(1):x(2),y(1):y(2),1);
         A(:,:,2) = im(x(1):x(2),y(1):y(2),2);
         A(:,:,3) = im(x(1):x(2),y(1):y(2),3);
         m=imgaussfilt(A,2);
        
         gbim = im;
         gbim(x(1):x(2),y(1):y(2),:) = m;
        
        %  figure;  imshow(gbim);  title('after blurring');
        %  imwrite(gbim, 'after blurring.jpg');
        end

        function F = cross_ratio(x,c)
        % use to calculate pixel distance with the same space distance.
        % input:    x = [x,y] unknown pixel coordinate
        %           c = [vx,vy: x,y-coordinate of vanishing point
        %                ax,ay: x,y-coordinate of the start point.
        %                bx,by: x,y-coordinate of the middle point
        %               ]
        % output:  //
        
        F = [norm([x(1)-c(3),x(2)-c(4)])*norm([c(5)-c(1),c(6)-c(2)])-2*norm([c(5)-x(1),c(6)-x(2)])*norm([c(3)-c(1),c(4)-c(2)]);
            (x(1)-c(1))/(x(2)-c(2))-(c(3)-c(1))/(c(4)-c(2))];
        
        end
        

        function [h, t] = computeH(s, d)
            s = [s(:,1:4); ones(1,4)];
            d = [d(:,1:4); ones(1,4)];
            
            % SVD
            A = [kron(s(:,1),x_hat(d(:,1)))';
                kron(s(:,2),x_hat(d(:,2)))';
                kron(s(:,3),x_hat(d(:,3)))';
                kron(s(:,4),x_hat(d(:,4)))'];
            [~,~,V] = svd(A);
            H = reshape(V(:,end),[3,3])';
            [~,sigma,~] = svd(H);
            h = H/H(3,3);
        
            % Use maketform to generate a transformation that will construct a projective
            % transform by providing a H matrix.
            t = maketform ('projective', h);
        end

%%

        function [xmin,xmax,ymin,ymax,gim,g_sele,alpha,gf] = buildselection(im)

        %g: selected foreground
        %gim: origin picture with blurred picture 
        %xmin,xmax,ymin,ymax: outer frame of the foregrounds
        %g_sele: foreground rectangles
        
        [mask,xi2,yi2] = roipoly(im);% generates a binary plot black 0 white 1 and
                                     % enables the user to click on the stroke point 
                                     % and double-click to end; outputs the graph(mask) 
                                     % inside the points and the points
        
        ymin = round(min(xi2));
        xmin = round(min(yi2));
        ymax = round(max(xi2));
        xmax = round(max(yi2));
        x = [xmin;xmax];
        y = [ymin;ymax];
        red=immultiply(mask,im(:,:,1));
        blue=immultiply(mask,im(:,:,3));
        green=immultiply(mask,im(:,:,2));
        g=cat(3,red,green,blue);
        
        maskf = ~mask;
        redf=immultiply(maskf,im(:,:,1));
        bluef=immultiply(maskf,im(:,:,3));
        greenf=immultiply(maskf,im(:,:,2));
        gf=cat(3,redf,greenf,bluef);%make the Foreground black 
        %imshow(gf);
        
        %create gaussian noise for the selected area
        gbim = filtered(g, x, y);
        gim = gf+gbim;
        
        
        %determine the smallest square(s) which surround the foreground(s)
        [i,j] = find(g(:,:,1)~=0);
        ximax = max(j);
        ximin = min(j);
        yimax = max(i);
        yimin = min(i);
        g_sele = g(yimin:yimax,ximin:ximax,:);
        %figure;
        %imshow(g_sele);
        siz = size(g_sele);
        alpha = ones(siz(1), siz(2));
        alpha(g_sele(:,:,2)==0)=0;
        % Move the pointer over the initial vertex of the polygon that you 
        % selected. The pointer changes to a circle . Click either mouse button.
        % 
        % Double-click the left mouse button. This action creates a vertex 
        % at the point under the mouse pointer and draws a straight line
        % connecting this vertex with the initial vertex.
        % 
        % Right-click the mouse. This draws a line connecting the last 
        % vertex selected with the initial vertex; it does not create a
        % new vertex at the point under the mouse.
        end


%% find line and corners
        
        function y = find_line_y(x1,y1,x2,y2,x)
        m = (y1-y2)./(x1-x2);
        b = y1 - m*x1;
        y = m*x + b;
        end

        function x = find_line_x(x1,y1,x2,y2,y)

        m = (y1-y2)./(x1-x2);
        b = y1 - m*x1;
        x = (y-b)./m;
        end

        function [x,y] = find_corner(vx,vy,rx,ry,limitx,limity)
        y1 = limity;
        x1 = find_line_x(vx,vy,rx,ry,limity);
        x2 = limitx;
        y2 = find_line_y(vx,vy,rx,ry,limitx);
        if (sum(([vx vy]-[x1 y1]).^2) > sum(([vx vy]-[x2 y2]).^2)),
            x = x1;
            y = y1;
        else
            x = x2;
            y = y2;
        end
        end
        
       function m = x_hat(x)
        % change vetor into a 3x3 matrix
        % input:    x: a 1x3 vector
        % output:   m: a 3x3 matrix
        m = [0    -x(3)  x(2);
         x(3)  0    -x(1);
        -x(2)  x(1)  0];
        end
        
      function adjIm = rotate(im)
        % rotate the orignal image according to a user defined horizontal line
        
        while 1
          
          % get the vanishing point
          [x,y,button] = ginput(2);
        
          % if pressed ENTER, quit the loop
          if (isempty(button))
            break;
          end
          points = [x';y'];
          % draw
          imshow(im);
          hold on;
        
          plot(points(1,:), points(2,:),"Color",'r','LineWidth',2);
          hold off;
          drawnow;
        end
        
        direction = [points(1,1)-points(2,1);points(2,1)-points(2,2)];
        theta = atan(direction(2)/direction(1));
        theta = theta * 180 / pi;
        adjIm = imrotate(im,theta);
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % APP初始化函数
            % Configure image axes
           
            addpath('datensatz');
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');%
            %axis(app.ImageAxes_2, 'image'); 
            % Update the image and histograms
            updateimage(app, 'datensatz/simple-room.png');
            app.current_imagefile = 'datensatz/simple-room.png';
            
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Read new pictures  
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
               msgbox("Load Success!","Load Image");
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          % read in sample image
        im = imread(app.current_imagefile);
        im = imresize(im,0.5);
        sz = size(im);
        focal_length = max(sz(1),sz(2))/2/tan(pi/180*30/2);
   

        %% deal with multiple vanishing points
        % how many VPs 1 or 2)
%         if app.Vanish_Point_Number == 1
%             flag_VP = 1;
%         else
%             flag_VP = 2;
%         end
        if app.Rotate_Flag == "Yes"
            msgbox("Do you need to rotate the picture to a suitable position? If yes, please choose 2 points creating a line which should be the horizon");
            figure('Name','Rotate Image');
            imshow(im);
            im = rotate(im); 
        end
        
        if app.Vanish_Point_Number == '2'           
           msgbox({'Select the 4 vertices of a trapezoid plane first from the left top corner in clockwise direction.';...
               'Then choose the direction of the 1st vanishing point (VP):';...
               'If VP is in the horizontal direction realtive to the trapezoid, then left clik the left side of the trapezoid'; ...
               'If VP is in the vertical direction realtive to the trapezoid, then left clik the upper side of the trapezoid'});
           msgbox("Calculation of 2 Vanish Points and Reconstruction of 3D model take up to 3-5 minutes, please be patient.","Warning","warn");
           figure('Name','Select to be deleted Vanish Point');
           imshow(im);
           Pos = ginput(5); % select the 4 vertices of a rectangle plan from the left 
                            % top corner in clockwise direction, then choose the direction 
                            % of the second vp (accroding to the 45 degree line)
           orgPos = Pos';
        
           im = proj_adjust(im,orgPos,focal_length);
           figure(2)
           imshow(im)
        end


        %% deal with foreground(s)
        %flag_f show if foreround exists
        flag_f = 0;     
        if app.Foreground_Number ~= 0
            flag_f = 1;
            gbim = im;
            msgbox("Please choose 3 Points on the picture, left upper, right under point and vanish point, prese ENTER to continue.");
            figure(1);
            [vx,vy,irx,iry,orx,ory] = TIP_GUI(im); %find the 5 rectangles and vanshing point
                gvx = vx;
                gvy = vy;
        
            for i = 1:app.Foreground_Number
                msgbox("select foreground using polyline, using double-click to finish inut");
                figure(1);
                [firx, firy,gbim, g_sele,alpha_sele,gf] = TIP_GUI_f(gbim,gvx, gvy, irx, iry, orx,ory);%select foreground and output the seleted picture
        
                [bim,bim_alpha,vx,vy,ceilrx,ceilry,floorrx,floorry,...
                leftrx,leftry,rightrx,rightry,backrx,backry,backfirx,backfiry] = ...
                TIP_get5rects_f(gbim,gvx,gvy,irx,iry,orx,ory, firx, firy);%Get the 
                    % expanded image, which includes the five boxes as well as the box
                    % of foreground, with coordinates in the expanded figure
                [ggbim,gbim_alpha,vx,vy,gceilrx,gceilry,gfloorrx,gfloorry,...
                gleftrx,gleftry,grightrx,grightry,gbackrx,gbackry,gbackfirx,gbackfiry] = ...
                TIP_get5rects_f(gf,gvx,gvy,irx,iry,orx,ory, firx, firy);%Process blurred images
        
        
                [botton_pointx,botton_pointy] = node([vx;vy], ...
                    [gbackfirx(4);gbackfiry(4)],[floorrx(3),floorry(3)], ...
                    [floorrx(4),floorry(4)]); %find the intersection point of the line 
                                              %build by vanishing point and the
                                              %lower left corner of foreground and
                                              %the underline of the floor to
                                              %roughly decide the projection y
                                              %coordinate in 3D scene
                                       
                if botton_pointx < 0 %make sure the projected foreground will not go
                                     % "beyond" the left wall.
                    botton_pointx = 0;
                end
        
                Firx{i} = firx;
                Firy{i} = firy;
                G_sele{i} = g_sele;
                Alpha_sele{i} = alpha_sele;
                Gf{i} = gf;
                Botton_pointx{i} = botton_pointx;
                Botton_pointy{i} = botton_pointy;
                Bim{i} = bim;
                Bim_alpha{i} = bim_alpha;
                Backfirx{i} = backfirx;
                Backfiry{i} = backfiry;
                Ggbim{i} = ggbim;
                Gbim_alpha{i} = gbim_alpha;
                Gbackfirx{i} = gbackfirx;
                Gbackfiry{i} = gbackfiry;
            end
        else
            msgbox("Please choose 3 Points on the picture, left upper, right under point and vanish point, prese ENTER to continue.");
            figure(1);
            [vx,vy,irx,iry,orx,ory] = TIP_GUI(im);
        
            % Find the cube faces and compute the expended image
            [bim,bim_alpha,vx,vy,ceilrx,ceilry,floorrx,floorry,...
            leftrx,leftry,rightrx,rightry,backrx,backry] = ...
            TIP_get5rects(im,vx,vy,irx,iry,orx,ory);
            
        end
        
        
         %predefine the five rectangles to make it easier later for spider mesh
            fiveRec = [floorrx(1),floorry(1);
                floorrx(2),floorry(2);
                floorrx(4),floorry(4);
                floorrx(3),floorry(3);
                leftrx(4),leftry(4);
                rightrx(3),rightry(3);
                ceilrx(4),ceilry(4);
                ceilrx(3),ceilry(3);
                ceilrx(1),ceilry(1);
                ceilrx(2),ceilry(2);
                leftrx(1),leftry(1);
                rightrx(2),rightry(2)];
        
        % Draw the Vanishing Point and the 4 faces on the image
        figure(2);
        imshow(bim);
        hold on;
        plot(vx,vy,'w*','LineWidth',5);
        plot([ceilrx ceilrx(1)], [ceilry ceilry(1)], 'y-','LineWidth',3);
        plot([floorrx floorrx(1)], [floorry floorry(1)], 'm-','LineWidth',3);
        plot([leftrx leftrx(1)], [leftry leftry(1)], 'c-','LineWidth',3);
        plot([rightrx rightrx(1)], [rightry rightry(1)], 'g-','LineWidth',3);
        hold off;

        %% Generate fronto-parallel views of each plane
        
        xmin = min([ceilrx(1) floorrx(4) leftrx(1)]);
        xmax = max([ceilrx(2) floorrx(3) rightrx(3)]);
        ymin = min([leftry(1) rightry(2) ceilry(1)]);
        ymax = max([leftry(4) rightry(3) floorry(3)]);
        
        destn = [xmin xmax xmax xmin; ymin ymin ymax ymax];
        spidery_mesh(vx,vy,size(im),fiveRec) %create spider mesh
        % Calculate 3D dimension
        % sim_trig_ratio = sqrt((xmax-xmin)*(ymax-ymin)/((backrx(2)-backrx(1))*(backry(3)-backry(2))));
        x1 = find_line_x(vx,vy,irx(1),iry(1),0);
        x2 = find_line_x(vx,vy,irx(2),iry(2),0);
        ratio1 = (fiveRec(9,1)-fiveRec(10,1))/(fiveRec(7,1)-fiveRec(8,1));
        ratio2 = (fiveRec(3,1)-fiveRec(4,1))/(fiveRec(1,1)-fiveRec(2,1));
        ratio3 = (fiveRec(11,2)-fiveRec(5,2))/(fiveRec(7,2)-fiveRec(1,2));
        ratio4 = (fiveRec(12,2)-fiveRec(6,2))/(fiveRec(8,2)-fiveRec(2,2));
        ratio = [ratio1,ratio2,ratio3,ratio4];
        sim_trig_ratio = max(ratio);
        %sim_trig_ratio = (x2-x1)/(irx(2)-irx(1));
        depth = focal_length*(sim_trig_ratio-1);

        %% transform of the image by homography matrix.
        source_ceil = [ceilrx; ceilry];
        [h_ceil,t_ceil] = computeH(source_ceil, destn);
        ceil = imtransform(bim, t_ceil, 'xData',[xmin xmax],'yData',[ymin ymax]);
        
        source_floor = [floorrx; floorry];
        [h_floor,t_floor] = computeH(source_floor, destn);
        floor = imtransform(bim, t_floor, 'xData',[xmin xmax],'yData',[ymin ymax]);
        
        source_back = [backrx; backry];
        [h_back,t_back] = computeH(source_back, destn);
        back = imtransform(bim, t_back, 'xData',[xmin xmax],'yData',[ymin ymax]);
        alpha_b = ones(size(back,1),size(back,2));
        
        source_left = [leftrx; leftry];
        [h_left,t_left] = computeH(source_left, destn);
        left = imtransform(bim, t_left, 'xData',[xmin xmax],'yData',[ymin ymax]);
        
        source_right = [rightrx; rightry];
        [h_right,t_right] = computeH(source_right, destn);
        right = imtransform(bim, t_right, 'xData',[xmin xmax],'yData',[ymin ymax]);
        % figure(7); imshow(right); axis image;

        %% deal with foreground(s)
        if flag_f ~= 0
            for i = 1:app.Foreground_Number
                gsource_floor = [floorrx; floorry];
                [gh_floor,gt_floor] = computeH(gsource_floor, destn);
                gfloor = imtransform(Ggbim{i}, gt_floor, 'xData',[xmin xmax],'yData',[ymin ymax]);
               %  figure(4); imshow(gfloor); axis image;
                [rowfq, colfq] = find(gfloor(20:(end-20),20:(end-20),1) ==0);
                gyf = size(gfloor(:,:,1),1) - max(rowfq);
            
                source_foreground = [Backfirx{i}; Backfiry{i}];
                [h_foreground,t_foreground] = computeH(source_foreground, destn);
                foreground = imtransform(G_sele{i}, t_foreground,'XYScale',1);
                size_f = size(foreground);
                alpha_foreground = imtransform(Alpha_sele{i}, t_foreground,'XYScale',1);
        
                Gyf{i} = gyf;
                Foreground{i} = foreground;
                Alpha_foreground{i} = alpha_foreground;
        
            end
        end
        %% display 3D sufraces
        
%         ceil_planex = [0 0 0; depth depth depth];
%         ceil_planey = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
%         ceil_planez = [ymax ymax ymax; ymax ymax ymax];
%         
%         floor_planex = [depth depth depth; 0 0 0];
%         floor_planey = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
%         floor_planez = [ymin ymin ymin; ymin ymin ymin];
%         
%         back_planex = [depth depth depth; depth depth depth];
%         back_planey = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
%         back_planez = [ymax ymax ymax; ymin ymin ymin];
%         
%         left_planex = [0 depth/2 depth; 0 depth/2 depth];
%         left_planey = [xmin xmin xmin; xmin xmin xmin];
%         left_planez = [ymax ymax ymax; ymin ymin ymin];
%         
%         right_planex = [depth depth/2 0; depth depth/2 0];
%         right_planey = [xmax xmax xmax; xmax xmax xmax];
%         right_planez = [ymax ymax ymax; ymin ymin ymin];
        [~,ind]=max(ratio);
        
        switch(ind)
            case 1 % top
                full_depth = abs(fiveRec(9,2) - fiveRec(7,2));
                a=0;    %top
                b=(1-abs(fiveRec(3,2) - fiveRec(1,2))/full_depth)*depth;    %floor
                c=(1-abs(fiveRec(11,2) - fiveRec(7,2))/full_depth)*depth;   %left
                d=(1-abs(fiveRec(12,2) - fiveRec(8,2))/full_depth)*depth;   %right
            case 2 % floor
                full_depth = abs(fiveRec(3,2) - fiveRec(1,2));
                a=(1-abs(fiveRec(9,2) - fiveRec(7,2))/full_depth)*depth;
                b=0;
                c=(1-abs(fiveRec(5,2) - fiveRec(1,2))/full_depth)*depth;
                d=(1-abs(fiveRec(6,2) - fiveRec(2,2))/full_depth)*depth;
            case 3 % left
                full_depth = abs(fiveRec(11,1) - fiveRec(7,1));
                a=(1-abs(fiveRec(9,1) - fiveRec(7,1))/full_depth)*depth;
                b=(1-abs(fiveRec(3,1) - fiveRec(1,1))/full_depth)*depth;
                c=0;
                d=(1-abs(fiveRec(12,1) - fiveRec(8,1))/full_depth)*depth;
            case 4 % right
                full_depth = abs(fiveRec(12,1) - fiveRec(8,1));
                a=(1-abs(fiveRec(10,1) - fiveRec(8,1))/full_depth)*depth;
                b=(1-abs(fiveRec(4,1) - fiveRec(2,1))/full_depth)*depth;
                c=(1-abs(fiveRec(11,1) - fiveRec(7,1))/full_depth)*depth;
                d=0;
        end
        
        ceil_planex = [a a a ; depth depth depth];
        ceil_planey = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
        ceil_planez = [ymax ymax ymax; ymax ymax ymax];
        
        floor_planex = [depth depth depth; b b b];
        floor_planey = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
        floor_planez = [ymin ymin ymin; ymin ymin ymin];
        
        back_planex = [depth depth depth; depth depth depth];
        back_planey = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
        back_planez = [ymax ymax ymax; ymin ymin ymin];
        
        left_planex = [c (depth-c)/2 depth; c (depth-c)/2 depth];
        left_planey = [xmin xmin xmin; xmin xmin xmin];
        left_planez = [ymax ymax ymax; ymin ymin ymin];
        
        right_planex = [depth (depth-d)/2 d; depth (depth-d)/2 d];
        right_planey = [xmax xmax xmax; xmax xmax xmax];
        right_planez = [ymax ymax ymax; ymin ymin ymin];
        
        %% deal with foreground(s)
        if flag_f ~= 0 
            for i = 1:app.Foreground_Number
                backfiry = Backfiry{i};
                foreground_depth = ymin + depth*(backfiry(1)-floorry(1))/(floorry(4)-floorry(1)) ;
                botton_pointx = Botton_pointx{i};
                foreground_left = botton_pointx;
                gbackfirx = Gbackfirx{i};
                foreground_right = foreground_left +1.5*(gbackfirx(3)-gbackfirx(4)) ; %enlarge the foregrounds with a suitable parameter 1.5
                foreground_height = 1.5*abs(backfiry(1)-backfiry(4));
                fl = foreground_left;
                fr = foreground_right;
                fh = foreground_height;
                fd = Gyf{i} * (depth/size(floor(:,:,1),1));
                foreground_x = [fd fd fd; fd fd fd];
                foreground_y = [fl (fr+fl)/2 fr; fl (fl+fr)/2 fr];
                foreground_z = [fh fh fh; 0 0 0];
         
                Foreground_x{i} = foreground_x;
                Foreground_y{i} = foreground_y;
                Foreground_z{i} = foreground_z;
            end
        
        end
        
        % create the surface and texturemap it with a given image
        
        view = figure('name','3DViewer');
        set(view,'windowkeypressfcn','set(gcbf,''Userdata'',get(gcbf,''CurrentCharacter''))') ;
        set(view,'windowkeyreleasefcn','set(gcbf,''Userdata'','''')') ;
        set(view,'Color','black');
         hold on
         %% warp the foreground(s)
        if flag_f ~= 0 
            for i = 1:app.Foreground_Number    
                h = warp(Foreground_x{i},Foreground_y{i},Foreground_z{i},Foreground{i}); 
                set(gcf,'renderer','opengl'); 
                set(h,'FaceAlpha',  'texturemap', 'AlphaDataMapping', 'none', 'AlphaData',Alpha_foreground{i});
            
            end
        
        end

        %% Wrap the images over the surface defined by the coordinates(x,y,z)
        warp(ceil_planex,ceil_planey,ceil_planez,ceil);
        warp(floor_planex,floor_planey,floor_planez,floor);
        warp(back_planex,back_planey,back_planez,back);
        warp(left_planex,left_planey,left_planez,left);
        warp(right_planex,right_planey,right_planez,right);
        
        
        % Show 3D-Space
        axis equal;  % make X,Y,Z dimentions be equal
        axis vis3d;  % freeze the scale for better rotations
        axis off;    % turn off the stupid tick marks
        camproj('perspective');  % make it a perspective projection
        
        % set camera position and Direction
        camx = -2000;
        camy = xmax/2;
        camz = ymax/2;
        campos([camx camy camz]);
        camup([0,0,1]);
        camtarget([0,xmax/2,ymax/2]);


        end

        % Button pushed function: Button_right
        function Button_rightPushed(app, event)
           if strcmp(app.Rotate, "Rotate")
                camdolly(-0.1*app.step_size,0,0,'fixtarget');
           else
                camdolly(-0.1*app.step_size,0,0,'movetarget');
           end
        end

        % Button pushed function: Button_left
        function Button_leftPushed(app, event)
           if strcmp(app.Rotate, "Rotate")
                camdolly(0.1*app.step_size,0,0,'fixtarget');
           else
                camdolly(0.1*app.step_size,0,0,'movetarget');
           end
        end

        % Button pushed function: Button_up
        function Button_upPushed(app, event)

           if strcmp(app.Rotate, "Rotate")
               camdolly(0,-0.05*app.step_size,0,'fixtarget');
           else
               camdolly(0,-0.05*app.step_size,0,'movetarget');
           end                
              
        end

        % Button pushed function: Button_down
        function Button_downPushed(app, event)
            if strcmp(app.Rotate, "Rotate")
                camdolly(0,0.05*app.step_size,0,'fixtarget');
            else
                camdolly(0,0.05*app.step_size,0,'movetarget');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.Rotate = app.Switch.Value;       
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            if camva()>5
                camzoom(1+0.1*app.step_size);
            else 
                camva(5);
            end
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            %camdolly(0,0,-0.05*app.step_size,'fixtarget');
           
            if camva()<80
                camzoom(1-0.1*app.step_size);
            else 
                camva(80);
            end
        end

        % Value changed function: StepSizeSlider
        function StepSizeSliderValueChanged(app, event)
            value = app.StepSizeSlider.Value;
            app.step_size = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           
            answer = questdlg('Do you really want to stop?','Warn','yes','No','No');
            if strcmp(answer,'yes')
                close all;
            else 
                return;
            end
        end

        % Value changed function: XCoordinateEditField
        function XCoordinateEditFieldValueChanged(app, event)
            if isnumeric(app.XCoordinateEditField.Value)
                app.camposition(1) = app.XCoordinateEditField.Value;
            end
            
        end

        % Value changed function: YCoordinateEditField
        function YCoordinateEditFieldValueChanged(app, event)
            if isnumeric(app.YCoordinateEditField.Value)
                app.camposition(2) = app.YCoordinateEditField.Value;
            end
        end

        % Value changed function: ZCoordinateEditField
        function ZCoordinateEditFieldValueChanged(app, event)
            if isnumeric(app.ZCoordinateEditField.Value)
                app.camposition(3) = app.ZCoordinateEditField.Value;
            end
        end

        % Value changed function: XDirectionEditField
        function XDirectionEditFieldValueChanged(app, event)
            if isnumeric(app.XDirectionEditField.Value)
                app.cam_direction_vector(1) = app.XDirectionEditField.Value;
            end
        end

        % Value changed function: YDirectionEditField
        function YDirectionEditFieldValueChanged(app, event)
            if isnumeric(app.YDirectionEditField.Value)
                app.cam_direction_vector(2) = app.YDirectionEditField.Value;
            end
        end

        % Value changed function: ZDirectionEditField
        function ZDirectionEditFieldValueChanged(app, event)
            if isnumeric(app.ZDirectionEditField.Value)
                app.cam_direction_vector(3) = app.ZDirectionEditField.Value;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            campos(app.camposition);
            app.cam_direction_vector = app.camposition-app.cam_direction_vector;
            if app.cam_direction_vector(1)==0 && app.cam_direction_vector(2)==0
                app.cam_direction_vector = app.cam_direction_vector + [0,0.001,0];
            end
            camtarget(app.cam_direction_vector);
        end

        % Value changed function: VanishPointNumDropDown
        function VanishPointNumDropDownValueChanged(app, event)
            value = app.VanishPointNumDropDown.Value;
            app.Vanish_Point_Number = value;
        end

        % Value changed function: ForegroundNumEditField
        function ForegroundNumEditFieldValueChanged(app, event)
            value = app.ForegroundNumEditField.Value;
            if isnumeric(value)
                app.Foreground_Number = round(value);
            else 
                warning("Please type in a integer");
            end
        end

        % Drop down opening function: RotateDropDown
        function RotateDropDownOpening(app, event)
  
        end

        % Value changed function: RotateDropDown
        function RotateDropDownValueChanged(app, event)
            app.Rotate_Flag = app.RotateDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Tour into Picture';
            app.UIFigure.Icon = 'tum-logo.png';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'simple-room', 'shopping-mall', 'uhren-turm', 'metro-station', 'oil-painting', 'sagrada_familia'};
            app.DropDown.ItemsData = {'simple-room.png', 'shopping-mall.png', 'uhren-turm.jpg', 'metro-station.png', 'oil-painting.png', 'sagrada_familia.png'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'simple-room.png';
            app.ImagesDatensatzLabel = uilabel(app.UIFigure);
            app.ImagesDatensatzLabel.Text = 'Dataset';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'z_tum-logo.png';
            app.TechnischeUniversittMnchenComputerVisionChallengeGruppe25Label = uilabel(app.UIFigure);
            app.TechnischeUniversittMnchenComputerVisionChallengeGruppe25Label.Text = {'Technische Universität München'; 'Computer Vision EI2223 '; 'Challenge Gruppe 25'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GUIModeTab = uitab(app.TabGroup);
            app.GUIModeTab.Title = 'GUI Mode';
            app.Button_left = uibutton(app.GUIModeTab, 'push');
            app.Button_left.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed, true);
            app.Button_left.Icon = 'z_arrow_left.png';
            app.Button_left.Text = '';
            app.Button_right = uibutton(app.GUIModeTab, 'push');
            app.Button_right.ButtonPushedFcn = createCallbackFcn(app, @Button_rightPushed, true);
            app.Button_right.Icon = 'z_arrow_right.png';
            app.Button_right.Text = '';
            app.Button_down = uibutton(app.GUIModeTab, 'push');
            app.Button_down.ButtonPushedFcn = createCallbackFcn(app, @Button_downPushed, true);
            app.Button_down.Icon = 'z_arrow_down.png';
            app.Button_down.Text = '';
            app.Button_up = uibutton(app.GUIModeTab, 'push');
            app.Button_up.ButtonPushedFcn = createCallbackFcn(app, @Button_upPushed, true);
            app.Button_up.Icon = 'z_arrow_up.png';
            app.Button_up.Text = '';
            app.Switch = uiswitch(app.GUIModeTab, 'slider');
            app.Switch.Items = {'Shift', 'Rotate'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Shift';
            app.ZoominButton = uibutton(app.GUIModeTab, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.GUIModeTab, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.ControlrangeLabel = uilabel(app.GUIModeTab);
            app.ControlrangeLabel.Text = 'Step Size';
            app.StepSizeSlider = uislider(app.GUIModeTab);
            app.StepSizeSlider.Limits = [1 10];
            app.StepSizeSlider.MajorTicks = [1 4 7 10];
            app.StepSizeSlider.ValueChangedFcn = createCallbackFcn(app, @StepSizeSliderValueChanged, true);
            app.StepSizeSlider.Value = 1;
            app.CameraPositionTab = uitab(app.TabGroup);
            app.CameraPositionTab.Title = 'Camera Position';
            app.XCoordinateEditFieldLabel = uilabel(app.CameraPositionTab);
            app.XCoordinateEditFieldLabel.Text = 'X-Coordinate';
            app.XCoordinateEditField = uieditfield(app.CameraPositionTab, 'numeric');
            app.XCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordinateEditFieldValueChanged, true);
            app.YCoordinateEditFieldLabel = uilabel(app.CameraPositionTab);
            app.YCoordinateEditFieldLabel.Text = 'Y-Coordinate';
            app.YCoordinateEditField = uieditfield(app.CameraPositionTab, 'numeric');
            app.YCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @YCoordinateEditFieldValueChanged, true);
            app.ZCoordinateEditFieldLabel = uilabel(app.CameraPositionTab);
            app.ZCoordinateEditFieldLabel.Text = 'Z-Coordinate';
            app.ZCoordinateEditField = uieditfield(app.CameraPositionTab, 'numeric');
            app.ZCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @ZCoordinateEditFieldValueChanged, true);
            app.CameraPositionDirectionVectorLabel = uilabel(app.CameraPositionTab);
            app.CameraPositionDirectionVectorLabel.Text = 'Camera Position&Direction Vector';
            app.XDirectionEditFieldLabel = uilabel(app.CameraPositionTab);
            app.XDirectionEditFieldLabel.Text = 'X-Direction';
            app.XDirectionEditField = uieditfield(app.CameraPositionTab, 'numeric');
            app.XDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XDirectionEditFieldValueChanged, true);
            app.YDirectionEditFieldLabel = uilabel(app.CameraPositionTab);
            app.YDirectionEditFieldLabel.Text = 'Y-Direction';
            app.YDirectionEditField = uieditfield(app.CameraPositionTab, 'numeric');
            app.YDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @YDirectionEditFieldValueChanged, true);
            app.ZDirectionEditFieldLabel = uilabel(app.CameraPositionTab);
            app.ZDirectionEditFieldLabel.Text = 'Z-Direction';
            app.ZDirectionEditField = uieditfield(app.CameraPositionTab, 'numeric');
            app.ZDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @ZDirectionEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.CameraPositionTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StartSetupPanel = uipanel(app.UIFigure);
            app.StartSetupPanel.Title = 'Start Setup';
            app.ForegroundNumEditFieldLabel = uilabel(app.StartSetupPanel);
            app.ForegroundNumEditFieldLabel.Text = 'Foreground Num';
            app.ForegroundNumEditField = uieditfield(app.StartSetupPanel, 'numeric');
            app.ForegroundNumEditField.ValueChangedFcn = createCallbackFcn(app, @ForegroundNumEditFieldValueChanged, true);
            app.ForegroundNumEditField.Tooltip = {'Choose number of  Foreground.'};
            app.VanishPointNumDropDownLabel = uilabel(app.StartSetupPanel);
            app.VanishPointNumDropDownLabel.Text = 'Vanish Point Num ';
            app.VanishPointNumDropDown = uidropdown(app.StartSetupPanel);
            app.VanishPointNumDropDown.Items = {'1', '2'};
            app.VanishPointNumDropDown.ValueChangedFcn = createCallbackFcn(app, @VanishPointNumDropDownValueChanged, true);
            app.VanishPointNumDropDown.Tooltip = {'Choose numbers of Vanish Point of chosen Image.'};
            app.VanishPointNumDropDown.Value = '1';
            app.StartButton = uibutton(app.StartSetupPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.StartSetupPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RotateDropDownLabel = uilabel(app.StartSetupPanel);
            app.RotateDropDownLabel.Text = 'Rotate';
            app.RotateDropDown = uidropdown(app.StartSetupPanel);
            app.RotateDropDown.Items = {'Yes', 'No'};
            app.RotateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RotateDropDownOpening, true);
            app.RotateDropDown.ValueChangedFcn = createCallbackFcn(app, @RotateDropDownValueChanged, true);
            app.RotateDropDown.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end